classdef IS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MCCEditField                   matlab.ui.control.NumericEditField
        MCCEditFieldLabel              matlab.ui.control.Label
        DiceEditField                  matlab.ui.control.NumericEditField
        DiceEditFieldLabel             matlab.ui.control.Label
        JaccardEditField               matlab.ui.control.NumericEditField
        JaccardEditFieldLabel          matlab.ui.control.Label
        FScoreEditField                matlab.ui.control.NumericEditField
        FScoreEditFieldLabel           matlab.ui.control.Label
        RecallEditField                matlab.ui.control.NumericEditField
        RecallEditFieldLabel           matlab.ui.control.Label
        PrecisionEditField             matlab.ui.control.NumericEditField
        PrecisionEditFieldLabel        matlab.ui.control.Label
        AccuracyEditField              matlab.ui.control.NumericEditField
        AccuracyEditFieldLabel         matlab.ui.control.Label
        LoadButton_3                   matlab.ui.control.Button
        GroundTruthLabel               matlab.ui.control.Label
        Image_3                        matlab.ui.control.Image
        LoadButton_2                   matlab.ui.control.Button
        QuantizedLabel                 matlab.ui.control.Label
        OriginalLabel                  matlab.ui.control.Label
        FAButton                       matlab.ui.control.Button
        HSButton                       matlab.ui.control.Button
        TLBOButton                     matlab.ui.control.Button
        UnitsLabel                     matlab.ui.control.Label
        SecondsLabel                   matlab.ui.control.Label
        IterationNumberEditField       matlab.ui.control.NumericEditField
        IterationNumberEditFieldLabel  matlab.ui.control.Label
        CostEditField                  matlab.ui.control.NumericEditField
        CostEditFieldLabel             matlab.ui.control.Label
        TimeEditField                  matlab.ui.control.NumericEditField
        TimeEditFieldLabel             matlab.ui.control.Label
        PopulationEditField            matlab.ui.control.NumericEditField
        PopulationEditFieldLabel       matlab.ui.control.Label
        IterationsEditField            matlab.ui.control.NumericEditField
        IterationsEditFieldLabel       matlab.ui.control.Label
        SegmentsEditField              matlab.ui.control.NumericEditField
        SegmentsEditFieldLabel         matlab.ui.control.Label
        Image_2                        matlab.ui.control.Image
        DEButton                       matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        LoadButton                     matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end


methods (Access = private)
function updateimage(app,imagefile)
img = imread(imagefile);
app.Image.ImageSource=img;
global img;
end
end

methods (Access = private)
function updateimage2(app,imagefile)
img2 = imread(imagefile);
app.Image_3.ImageSource=img2;
global img2;
end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Callback function
        function DropDownValueChanged(app, event)

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)

% Display uigetfile dialog
filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
[f, p] = uigetfile(filterspec);

% Make sure user didn't cancel uigetfile dialog
if (ischar(p))
fname = [p f];
updateimage(app, fname);
end
        end

        % Button pushed function: DEButton
        function DEButtonPushed(app, event)
global img;
img=im2double(img);
Ximg=img;

gray=rgb2gray(img);
gray=imadjust(gray);
% Reshaping image to vector
X=gray(:);

%% Starting 
k = app.SegmentsEditField.Value; % Number of Segments

%---------------------------------------------------
CostFunction=@(m) ClusterCost(m, X);     % Cost Function
VarSize=[k size(X,2)];           % Decision Variables Matrix Size
nVar=prod(VarSize);              % Number of Decision Variables
VarMin= repmat(min(X),k,1);      % Lower Bound of Variables
VarMax= repmat(max(X),k,1);      % Upper Bound of Variables


% DE Parameters
MaxIt=app.IterationsEditField.Value;         % Maximum Iterations
nPop=app.PopulationEditField.Value;         % Population Size
%
beta_min=0.2;   % Lower Bound of Scaling Factor
beta_max=0.8;   % Upper Bound of Scaling Factor
pCR=0.2;        % Crossover Probability

% Start
empty_individual.Position=[];
empty_individual.Cost=[];
empty_individual.Out=[];
BestSol.Cost=inf;
pop=repmat(empty_individual,nPop,1);
for i=1:nPop
pop(i).Position=unifrnd(VarMin,VarMax,VarSize);  
[pop(i).Cost, pop(i).Out]=CostFunction(pop(i).Position);  
if pop(i).Cost<BestSol.Cost
BestSol=pop(i);
end 
end
BestRes=zeros(MaxIt,1);

% DE Body
for it=1:MaxIt
for i=1:nPop        
x=pop(i).Position;        
A=randperm(nPop);        
A(A==i)=[];        
a=A(1);
b=A(1);
c=A(1);       
% Mutation
beta=unifrnd(beta_min,beta_max,VarSize);
y=pop(a).Position+beta.*(pop(b).Position-pop(c).Position);
y=max(y,VarMin);
y=min(y,VarMax);        
% Crossover
z=zeros(size(x));
j0=randi([1 numel(x)]);
for j=1:numel(x)
if j==j0 || rand<=pCR
z(j)=y(j);
else
z(j)=x(j);
end
end        
NewSol.Position=z;
[NewSol.Cost, NewSol.Out]=CostFunction(NewSol.Position);       
if NewSol.Cost<pop(i).Cost
pop(i)=NewSol;           
if pop(i).Cost<BestSol.Cost
BestSol=pop(i);
end
end
end    
% Update Best Cost
BestCost(it)=BestSol.Cost;    
% Iteration 
disp(['In Iteration # ' num2str(it) ': Highest Cost IS = ' num2str(BestCost(it))]);    
% DECenters=Res(X, BestSol);
app.IterationNumberEditField.Value=double(it);
pause(0.000000001);
drawnow;
end
FAlbl=BestSol.Out.ind;

toc
time=toc;
%% Converting cluster centers and its indexes into image 
gray2=reshape(FAlbl(:,1),size(gray));
segmented = label2rgb(gray2); 
segmented=im2double(segmented);
seg2=segmented;
global seg2;

% Plot
app.Image_2.ImageSource=segmented;
plot(app.UIAxes,BestCost,'k','LineWidth', 2);
app.TimeEditField.Value=time;
app.CostEditField.Value=double(BestCost(end));

        end

        % Button pushed function: TLBOButton
        function TLBOButtonPushed(app, event)
global img;
img=im2double(img);
Ximg=img;

gray=rgb2gray(img);
gray=imadjust(gray);
% Reshaping image to vector
X=gray(:);

%% Starting
k = app.SegmentsEditField.Value; % Number of Segments
tic
%---------------------------------------------------
CostFunction=@(m) ClusterCost(m, X);     % Cost Function
VarSize=[k size(X,2)];           % Decision Variables Matrix Size
nVar=prod(VarSize);              % Number of Decision Variables
VarMin= repmat(min(X),k,1);      % Lower Bound of Variables
VarMax= repmat(max(X),k,1);      % Upper Bound of Variables

% TLBO Parameters
MaxIt = app.IterationsEditField.Value;      % Maximum Number of Iterations
nPop = app.PopulationEditField.Value;        % Population Size (Swarm Size)

%% Start 

% Empty Individuals
empty_individual.Position = [];
empty_individual.Cost = [];
empty_individual.Out = [];

% Population Array
pop = repmat(empty_individual, nPop, 1);
% Initialize Best Solution
BestSol.Cost = inf;
% Population Members
for i = 1:nPop
pop(i).Position = unifrnd(VarMin, VarMax, VarSize);
[pop(i).Cost pop(i).Out] = CostFunction(pop(i).Position);
if pop(i).Cost < BestSol.Cost
BestSol = pop(i);
end
end
% Best Cost Record
BestCost = zeros(MaxIt, 1);

%% TLBO Body
for it = 1:MaxIt
% Calculate Population Mean
Mean = 0;
for i = 1:nPop
Mean = Mean + pop(i).Position;
end
Mean = Mean/nPop;
% Select Teacher
Teacher = pop(1);
for i = 2:nPop
if pop(i).Cost < Teacher.Cost
Teacher = pop(i);
end
end
% Teacher Phase
for i = 1:nPop
% Create Empty Solution
newsol = empty_individual;
% Teaching Factor
TF = randi([1 2]);
% Teaching (moving towards teacher)
newsol.Position = pop(i).Position ...
+ rand(VarSize).*(Teacher.Position - TF*Mean);
% Clipping
newsol.Position = max(newsol.Position, VarMin);
newsol.Position = min(newsol.Position, VarMax);
% Evaluation
[newsol.Cost newsol.Out] = CostFunction(newsol.Position);
% Comparision
if newsol.Cost<pop(i).Cost
pop(i) = newsol;
if pop(i).Cost < BestSol.Cost
BestSol = pop(i);
end
end
end
% Learner Phase
for i = 1:nPop
A = 1:nPop;
A(i) = [];
j = A(randi(nPop-1));
Step = pop(i).Position - pop(j).Position;
if pop(j).Cost < pop(i).Cost
Step = -Step;
end
% Create Empty Solution
newsol = empty_individual;
% Teaching (moving towards teacher)
newsol.Position = pop(i).Position + rand(VarSize).*Step;
% Clipping
newsol.Position = max(newsol.Position, VarMin);
newsol.Position = min(newsol.Position, VarMax);
% Evaluation
[newsol.Cost newsol.Out] = CostFunction(newsol.Position);
% Comparision
if newsol.Cost<pop(i).Cost
pop(i) = newsol;
if pop(i).Cost < BestSol.Cost
BestSol = pop(i);
end
end
end
BestCost(it) = BestSol.Cost;
% Iteration 
disp(['In Iteration ' num2str(it) ': TLBO Cost Is = ' num2str(BestCost(it))]);
% FACenters=Res(X, BestSol);
app.IterationNumberEditField.Value=double(it);
pause(0.000000001);
drawnow;
end
FAlbl=BestSol.Out.ind;

toc
time=toc;
%% Converting cluster centers and its indexes into image 
gray2=reshape(FAlbl(:,1),size(gray));
segmented = label2rgb(gray2); 
segmented=im2double(segmented);
seg2=segmented;
global seg2;

% Plot
app.Image_2.ImageSource=segmented;
plot(app.UIAxes,BestCost,'k','LineWidth', 2);
app.TimeEditField.Value=time;
app.CostEditField.Value=double(BestCost(end));


        end

        % Button pushed function: HSButton
        function HSButtonPushed(app, event)
global img;
img=im2double(img);
Ximg=img;

gray=rgb2gray(img);
gray=imadjust(gray);
% Reshaping image to vector
X=gray(:);

%% Starting
k = app.SegmentsEditField.Value; % Number of Segments
tic
%---------------------------------------------------
CostFunction=@(m) ClusterCost(m, X);     % Cost Function
VarSize=[k size(X,2)];           % Decision Variables Matrix Size
nVar=prod(VarSize);              % Number of Decision Variables
VarMin= repmat(min(X),k,1);      % Lower Bound of Variables
VarMax= repmat(max(X),k,1);      % Upper Bound of Variables

%% Harmony Search Parameters

MaxIt=app.IterationsEditField.Value;         % Maximum Iterations
HMS=app.PopulationEditField.Value;         % Population Size
nNew = 4;        % Number of New Harmonies

HMCR = 0.9;       % Harmony Memory Consideration Rate
PAR = 0.1;        % Pitch Adjustment Rate
FW = 0.02*(VarMax-VarMin);    % Fret Width (Bandwidth)
FW_damp = 0.995;              % Fret Width Damp Ratio

%% Initialization

% Empty Harmony Structure
empty_harmony.Position = [];
empty_harmony.Cost = [];
empty_harmony.Out = [];

% Initialize Harmony Memory
HM = repmat(empty_harmony, HMS, 1);

% Create Initial Harmonies
for i = 1:HMS
HM(i).Position = unifrnd(VarMin, VarMax, VarSize);
[HM(i).Cost HM(i).Out] = CostFunction(HM(i).Position);
end

% Sort Harmony Memory
[~, SortOrder] = sort([HM.Cost]);
HM = HM(SortOrder);

% Update Best Solution Ever Found
BestSol = HM(1);

% Array to Hold Best Cost Values
BestCost = zeros(MaxIt, 1);

%% Harmony Search Main Loop

for it = 1:MaxIt

% Initialize Array for New Harmonies
NEW = repmat(empty_harmony, nNew, 1);

% Create New Harmonies
for k = 1:nNew

% Create New Harmony Position
NEW(k).Position = unifrnd(VarMin, VarMax, VarSize);
for j = 1:nVar
if rand <= HMCR
% Use Harmony Memory
i = randi([1 HMS]);
NEW(k).Position(j) = HM(i).Position(j);
end

% Pitch Adjustment
if rand <= PAR
% DELTA = FW*unifrnd(-1, +1);    % Uniform
DELTA = FW*randn();            % Gaussian (Normal) 
NEW(k).Position(j) = NEW(k).Position(j);
end
end

% Apply Variable Limits
NEW(k).Position = max(NEW(k).Position, VarMin);
NEW(k).Position = min(NEW(k).Position, VarMax);

% Evaluation
[NEW(k).Cost NEW(k).Out] = CostFunction(NEW(k).Position);
end

% Merge Harmony Memory and New Harmonies
HM = [HM
NEW]; 

% Sort Harmony Memory
[~, SortOrder] = sort([HM.Cost]);
HM = HM(SortOrder);

% Truncate Extra Harmonies
HM = HM(1:HMS);

% Update Best Solution Ever Found
BestSol = HM(1);

% Store Best Cost Ever Found
BestCost(it) = BestSol.Cost;
BestRes(it)=BestSol.Cost;    
% Show Iteration Information
disp(['Iteration ' num2str(it) ': Best Cost = ' num2str(BestCost(it))]);
% Damp Fret Width
FW = FW*FW_damp;
% FACenters=Res(X, BestSol);
app.IterationNumberEditField.Value=double(it);
pause(0.000000001);
drawnow;
end
FAlbl=BestSol.Out.ind;

toc
time=toc;
%% Converting cluster centers and its indexes into image 
gray2=reshape(FAlbl(:,1),size(gray));
segmented = label2rgb(gray2); 
segmented=im2double(segmented);
seg2=segmented;
global seg2;

% Plot
app.Image_2.ImageSource=segmented;
plot(app.UIAxes,BestCost,'k','LineWidth', 2);
app.TimeEditField.Value=time;
app.CostEditField.Value=double(BestCost(end));

        end

        % Button pushed function: FAButton
        function FAButtonPushed(app, event)
global img;
img=im2double(img);
Ximg=img;

gray=rgb2gray(img);
gray=imadjust(gray);
% Reshaping image to vector
X=gray(:);

%% Starting
k = app.SegmentsEditField.Value; % Number of Segments
tic
%---------------------------------------------------
CostFunction=@(m) ClusterCost(m, X);     % Cost Function
VarSize=[k size(X,2)];           % Decision Variables Matrix Size
nVar=prod(VarSize);              % Number of Decision Variables
VarMin= repmat(min(X),k,1);      % Lower Bound of Variables
VarMax= repmat(max(X),k,1);      % Upper Bound of Variables

% Firefly Algorithm Parameters
MaxIt=app.IterationsEditField.Value;         % Maximum Iterations
nPop=app.PopulationEditField.Value;         % Population Size

gamma = 1;            % Light Absorption Coefficient
beta0 = 2;            % Attraction Coefficient Base Value
alpha = 0.2;          % Mutation Coefficient
alpha_damp = 0.98;    % Mutation Coefficient Damping Ratio
delta = 0.05*(VarMax-VarMin);     % Uniform Mutation Range
m = 2;
if isscalar(VarMin) && isscalar(VarMax)
dmax = (VarMax-VarMin)*sqrt(nVar);
else
dmax = norm(VarMax-VarMin);
end

%% Start
% Empty Firefly Structure
firefly.Position = [];
firefly.Cost = [];
firefly.Out = [];
% Initialize Population Array
pop = repmat(firefly, nPop, 1);
% Initialize Best Solution Ever Found
BestSol.Cost = inf;
% Create Initial Fireflies
for i = 1:nPop
pop(i).Position = unifrnd(VarMin, VarMax, VarSize);
[pop(i).Cost, pop(i).Out] = CostFunction(pop(i).Position);
if pop(i).Cost <= BestSol.Cost
BestSol = pop(i);
end
end
% Array to Hold Best Cost Values
BestCost = zeros(MaxIt, 1);

%% Firefly Algorithm Main Loop
for it = 1:MaxIt
newpop = repmat(firefly, nPop, 1);
for i = 1:nPop
newpop(i).Cost = inf;
for j = 1:nPop
if pop(j).Cost < pop(i).Cost
rij = norm(pop(i).Position-pop(j).Position)/dmax;
beta = beta0.*exp(-gamma.*rij^m);
e = delta.*unifrnd(-1, +1, VarSize);
%e = delta*randn(VarSize);
newsol.Position = pop(i).Position ...
+ beta.*rand(VarSize).*(pop(j).Position-pop(i).Position) ...
+ alpha.*e;
newsol.Position = max(newsol.Position, VarMin);
newsol.Position = min(newsol.Position, VarMax);
[newsol.Cost newsol.Out] = CostFunction(newsol.Position);
if newsol.Cost <= newpop(i).Cost
newpop(i) = newsol;
if newpop(i).Cost <= BestSol.Cost
BestSol = newpop(i);
end
end
end
end
end
% Merge
pop = [pop
newpop];  
% Sort
[~, SortOrder] = sort([pop.Cost]);
pop = pop(SortOrder);
% Truncate
pop = pop(1:nPop);
% Store Best Cost Ever Found
BestCost(it) = BestSol.Cost;
disp(['Iteration ' num2str(it) ': Best Cost = ' num2str(BestCost(it))]);
% Damp Mutation Coefficient
alpha = alpha*alpha_damp;
app.IterationNumberEditField.Value=double(it);
pause(0.000000001);
drawnow;
end
FAlbl=BestSol.Out.ind;

toc
time=toc;
%% Converting cluster centers and its indexes into image 
gray2=reshape(FAlbl(:,1),size(gray));
segmented = label2rgb(gray2); 
segmented=im2double(segmented);
seg2=segmented;
global seg2;

% Plot
app.Image_2.ImageSource=segmented;
plot(app.UIAxes,BestCost,'k','LineWidth', 2);
app.TimeEditField.Value=time;
app.CostEditField.Value=double(BestCost(end));

        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            % Display uigetfile dialog
filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
[f, p] = uigetfile(filterspec);

% Make sure user didn't cancel uigetfile dialog
if (ischar(p))
fname = [p f];
updateimage2(app, fname);
end


        end

        % Button pushed function: LoadButton_3
        function LoadButton_3Pushed(app, event)
            
global img2;
global seg2;
img2=im2double(img2);

%Statistics
seg=seg2;
seg=imbinarize(rgb2gray(seg2));
gt=img2;
gt=imbinarize(rgb2gray(img2));

X=gt;
Y=seg;
sumindex = X + Y;
TP = length(find(sumindex == 2));
TN = length(find(sumindex == 0));
substractindex = X - Y;
FP = length(find(substractindex == -1));
FN = length(find(substractindex == 1));
Accuracy = (TP+TN)/(FN+FP+TP+TN);
Sensitivity = TP/(TP+FN);
Precision = TP/(TP+FP);
Fmeasure = 2*TP/(2*TP+FP+FN);
MCC = (TP*TN-FP*FN)/sqrt((TP+FP)*(TP+FN)*(TN+FP)*(TN+FN));
Dice = 2*TP/(2*TP+FP+FN);
Jaccard = Dice/(2-Dice);
% Specitivity = TN/(TN+FP);

app.AccuracyEditField.Value=Accuracy;
app.PrecisionEditField.Value=Precision;
app.RecallEditField.Value=Sensitivity;
app.FScoreEditField.Value=Fmeasure;
app.JaccardEditField.Value=Jaccard;
app.DiceEditField.Value=Dice;
app.MCCEditField.Value=MCC;


        end

        % Callback function
        function ButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Segmentation';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cost')
            xlabel(app.UIAxes, 'Iterations')
            ylabel(app.UIAxes, 'Cost Value')
            zlabel(app.UIAxes, 'Z')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Image';
            app.Image = uiimage(app.UIFigure);
            app.DEButton = uibutton(app.UIFigure, 'push');
            app.DEButton.ButtonPushedFcn = createCallbackFcn(app, @DEButtonPushed, true);
            app.DEButton.Text = 'DE';
            app.Image_2 = uiimage(app.UIFigure);
            app.SegmentsEditFieldLabel = uilabel(app.UIFigure);
            app.SegmentsEditFieldLabel.Text = 'Segments';
            app.SegmentsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SegmentsEditField.Value = 2;
            app.IterationsEditFieldLabel = uilabel(app.UIFigure);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationsEditField.Value = 10;
            app.PopulationEditFieldLabel = uilabel(app.UIFigure);
            app.PopulationEditFieldLabel.Text = 'Population';
            app.PopulationEditField = uieditfield(app.UIFigure, 'numeric');
            app.PopulationEditField.Value = 15;
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.CostEditFieldLabel = uilabel(app.UIFigure);
            app.CostEditFieldLabel.Text = 'Cost';
            app.CostEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationNumberEditFieldLabel = uilabel(app.UIFigure);
            app.IterationNumberEditFieldLabel.Text = 'Iteration Number';
            app.IterationNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.SecondsLabel = uilabel(app.UIFigure);
            app.SecondsLabel.Text = 'Seconds';
            app.UnitsLabel = uilabel(app.UIFigure);
            app.UnitsLabel.Text = 'Units';
            app.TLBOButton = uibutton(app.UIFigure, 'push');
            app.TLBOButton.ButtonPushedFcn = createCallbackFcn(app, @TLBOButtonPushed, true);
            app.TLBOButton.Text = 'TLBO';
            app.HSButton = uibutton(app.UIFigure, 'push');
            app.HSButton.ButtonPushedFcn = createCallbackFcn(app, @HSButtonPushed, true);
            app.HSButton.Text = 'HS';
            app.FAButton = uibutton(app.UIFigure, 'push');
            app.FAButton.ButtonPushedFcn = createCallbackFcn(app, @FAButtonPushed, true);
            app.FAButton.Text = 'FA';
            app.OriginalLabel = uilabel(app.UIFigure);
            app.OriginalLabel.Text = 'Original';
            app.QuantizedLabel = uilabel(app.UIFigure);
            app.QuantizedLabel.Text = 'Quantized';
            app.LoadButton_2 = uibutton(app.UIFigure, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load Ground Truth';
            app.Image_3 = uiimage(app.UIFigure);
            app.GroundTruthLabel = uilabel(app.UIFigure);
            app.GroundTruthLabel.Text = 'Ground Truth';
            app.LoadButton_3 = uibutton(app.UIFigure, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_3Pushed, true);
            app.LoadButton_3.Text = 'Statistics';
            app.AccuracyEditFieldLabel = uilabel(app.UIFigure);
            app.AccuracyEditFieldLabel.Text = 'Accuracy';
            app.AccuracyEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrecisionEditFieldLabel = uilabel(app.UIFigure);
            app.PrecisionEditFieldLabel.Text = 'Precision';
            app.PrecisionEditField = uieditfield(app.UIFigure, 'numeric');
            app.RecallEditFieldLabel = uilabel(app.UIFigure);
            app.RecallEditFieldLabel.Text = 'Recall';
            app.RecallEditField = uieditfield(app.UIFigure, 'numeric');
            app.FScoreEditFieldLabel = uilabel(app.UIFigure);
            app.FScoreEditFieldLabel.Text = 'F-Score';
            app.FScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.JaccardEditFieldLabel = uilabel(app.UIFigure);
            app.JaccardEditFieldLabel.Text = 'Jaccard';
            app.JaccardEditField = uieditfield(app.UIFigure, 'numeric');
            app.DiceEditFieldLabel = uilabel(app.UIFigure);
            app.DiceEditFieldLabel.Text = 'Dice';
            app.DiceEditField = uieditfield(app.UIFigure, 'numeric');
            app.MCCEditFieldLabel = uilabel(app.UIFigure);
            app.MCCEditFieldLabel.Text = 'MCC';
            app.MCCEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end