classdef PMS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TasksEditField                 matlab.ui.control.NumericEditField
        TasksEditFieldLabel            matlab.ui.control.Label
        MachinesEditField              matlab.ui.control.NumericEditField
        MachinesEditFieldLabel         matlab.ui.control.Label
        SetupTimeMaxEditField          matlab.ui.control.NumericEditField
        SetupTimeMaxEditFieldLabel     matlab.ui.control.Label
        SetupTimeMinEditField          matlab.ui.control.NumericEditField
        SetupTimeMinEditFieldLabel     matlab.ui.control.Label
        ProcessTimeMaxEditField        matlab.ui.control.NumericEditField
        ProcessTimeMaxEditFieldLabel   matlab.ui.control.Label
        ProcessTimeMinEditField        matlab.ui.control.NumericEditField
        ProcessTimeMinEditFieldLabel   matlab.ui.control.Label
        HSButton                       matlab.ui.control.Button
        FAButton                       matlab.ui.control.Button
        TLBOButton                     matlab.ui.control.Button
        UnitsLabel                     matlab.ui.control.Label
        SecondsLabel                   matlab.ui.control.Label
        IterationNumberEditField       matlab.ui.control.NumericEditField
        IterationNumberEditFieldLabel  matlab.ui.control.Label
        CostEditField                  matlab.ui.control.NumericEditField
        CostEditFieldLabel             matlab.ui.control.Label
        TimeEditField                  matlab.ui.control.NumericEditField
        TimeEditFieldLabel             matlab.ui.control.Label
        PopulationEditField            matlab.ui.control.NumericEditField
        PopulationEditFieldLabel       matlab.ui.control.Label
        IterationsEditField            matlab.ui.control.NumericEditField
        IterationsEditFieldLabel       matlab.ui.control.Label
        DEButton                       matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end


% methods (Access = private)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Callback function
        function DropDownValueChanged(app, event)

        end

        % Button pushed function: DEButton
        function DEButtonPushed(app, event)

global NFE;
NFE=0;

%% Problem Definition
I=app.TasksEditField.Value; % Tasks
J=app.MachinesEditField.Value; % Machines
Pmin=app.ProcessTimeMinEditField.Value; % Process time  min
Pmax=app.ProcessTimeMaxEditField.Value; % Process time max
Smin=app.SetupTimeMinEditField.Value; % Setup time min
Smax=app.SetupTimeMaxEditField.Value; % Setup time max
model=CreateModel(I,J,Pmin,Pmax,Smin,Smax);        % Create Model of the Problem

CostFunction=@(x) MyCost(x,model);       % Cost Function
nVar=model.nVar;        % Number of Decision Variables
VarSize=[1 nVar];       % Size of Decision Variables Matrix
VarMin = 0;          % Lower Bound of Decision Variables
VarMax = 1;          % Upper Bound of Decision Variables

%% DE Parameters

MaxIt = app.IterationsEditField.Value;         % Maximum Number of Iterations
nPop = app.PopulationEditField.Value;           % Population Size

beta_min = 0.2;   % Lower Bound of Scaling Factor
beta_max = 0.8;   % Upper Bound of Scaling Factor
pCR = 0.2;        % Crossover Probability

tic

%% Initialization

empty_individual.Position = [];
empty_individual.Cost = [];
empty_individual.Sol = [];

BestSol.Cost = inf;
pop = repmat(empty_individual, nPop, 1);
for i = 1:nPop
pop(i).Position = unifrnd(VarMin, VarMax, VarSize);
[pop(i).Cost pop(i).Sol] = CostFunction(pop(i).Position);
if pop(i).Cost<BestSol.Cost
BestSol = pop(i);
end
end
BestCost = zeros(MaxIt, 1);

%% DE Main Loop

for it = 1:MaxIt
for i = 1:nPop
x = pop(i).Position;
A = randperm(nPop);
A(A == i) = [];
a = A(1);
b = A(2);
c = A(3);

% Mutation
%beta = unifrnd(beta_min, beta_max);
beta = unifrnd(beta_min, beta_max, VarSize);
y = pop(a).Position+beta.*(pop(b).Position-pop(c).Position);
y = max(y, VarMin);
y = min(y, VarMax);

% Crossover
z = zeros(size(x));
j0 = randi([1 numel(x)]);
for j = 1:numel(x)
if j == j0 || rand <= pCR
z(j) = y(j);
else
z(j) = x(j);
end
end

NewSol.Position = z;
[NewSol.Cost NewSol.Sol] = CostFunction(NewSol.Position);

if NewSol.Cost<pop(i).Cost
pop(i) = NewSol;
if pop(i).Cost<BestSol.Cost
BestSol = pop(i);
end
end
end
% Update Best Cost
BestCost(it) = BestSol.Cost;
% Show Iteration Information
% Store NFE
nfe(it)=NFE;
disp(['Iteration ' num2str(it) ': Best Cost = ' num2str(BestCost(it))])
app.IterationNumberEditField.Value=double(it);
pause(0.000000001);
drawnow;
end

toc
time=toc;
%% Show Results

plot(app.UIAxes,BestCost,'k','LineWidth', 2);
app.TimeEditField.Value=time;
app.CostEditField.Value=double(BestCost(end));

figure(1);
PlotSolution(BestSol.Sol,model);
        end

        % Button pushed function: TLBOButton
        function TLBOButtonPushed(app, event)


global NFE;
NFE=0;

%% Problem Definition
I=app.TasksEditField.Value; % Tasks
J=app.MachinesEditField.Value; % Machines
Pmin=app.ProcessTimeMinEditField.Value; % Process time  min
Pmax=app.ProcessTimeMaxEditField.Value; % Process time max
Smin=app.SetupTimeMinEditField.Value; % Setup time min
Smax=app.SetupTimeMaxEditField.Value; % Setup time max
model=CreateModel(I,J,Pmin,Pmax,Smin,Smax);        % Create Model of the Problem

CostFunction=@(x) MyCost(x,model);       % Cost Function
nVar=model.nVar;        % Number of Decision Variables
VarSize=[1 nVar];       % Size of Decision Variables Matrix
VarMin = 0;          % Lower Bound of Decision Variables
VarMax = 1;          % Upper Bound of Decision Variables

%% TLBO Parameters
MaxIt = app.IterationsEditField.Value;         % Maximum Number of Iterations
nPop = app.PopulationEditField.Value;           % Population Size

tic

%% Start 
% Empty Individuals
empty_individual.Position = [];
empty_individual.Cost = [];
empty_individual.Sol = [];

% Population Array
pop = repmat(empty_individual, nPop, 1);
% Initialize Best Solution
BestSol.Cost = inf;
% Population Members
for i = 1:nPop
pop(i).Position = unifrnd(VarMin, VarMax, VarSize);
[pop(i).Cost pop(i).Sol] = CostFunction(pop(i).Position);
if pop(i).Cost < BestSol.Cost
BestSol = pop(i);
end
end
% Best Cost Record
BestCost = zeros(MaxIt, 1);

%% TLBO 
for it = 1:MaxIt
% Calculate Population Mean
Mean = 0;
for i = 1:nPop
Mean = Mean + pop(i).Position;
end
Mean = Mean/nPop;
% Select Teacher
Teacher = pop(1);
for i = 2:nPop
if pop(i).Cost < Teacher.Cost
Teacher = pop(i);
end
end

% Teacher Phase
for i = 1:nPop
% Create Empty Solution
newsol = empty_individual;
% Teaching Factor
TF = randi([1 2]);
% Teaching (moving towards teacher)
newsol.Position = pop(i).Position ...
+ rand(VarSize).*(Teacher.Position - TF*Mean);
% Clipping
newsol.Position = max(newsol.Position, VarMin);
newsol.Position = min(newsol.Position, VarMax);
% Evaluation
[newsol.Cost newsol.Sol] = CostFunction(newsol.Position);
% Comparision
if newsol.Cost<pop(i).Cost
pop(i) = newsol;
if pop(i).Cost < BestSol.Cost
BestSol = pop(i);
end
end
end

% Learner Phase
for i = 1:nPop
A = 1:nPop;
A(i) = [];
j = A(randi(nPop-1));
Step = pop(i).Position - pop(j).Position;
if pop(j).Cost < pop(i).Cost
Step = -Step;
end
% Create Empty Solution
newsol = empty_individual;
% Teaching (moving towards teacher)
newsol.Position = pop(i).Position + rand(VarSize).*Step;
% Clipping
newsol.Position = max(newsol.Position, VarMin);
newsol.Position = min(newsol.Position, VarMax);
% Evaluation
[newsol.Cost newsol.Sol] = CostFunction(newsol.Position);
% Comparision
if newsol.Cost<pop(i).Cost
pop(i) = newsol;
if pop(i).Cost < BestSol.Cost
BestSol = pop(i);
end
end
end
BestCost(it) = BestSol.Cost;
% Iteration 
disp(['In ITR ' num2str(it) ': TLBO Cost Value Is = ' num2str(BestCost(it))]);
app.IterationNumberEditField.Value=double(it);
pause(0.000000001);
drawnow;

end

toc
time=toc;
%% Show Results

plot(app.UIAxes,BestCost,'k','LineWidth', 2);
app.TimeEditField.Value=time;
app.CostEditField.Value=double(BestCost(end));

figure(1);
PlotSolution(BestSol.Sol,model);

        end

        % Button pushed function: FAButton
        function FAButtonPushed(app, event)

global NFE;
NFE=0;

%% Problem Definition
I=app.TasksEditField.Value; % Tasks
J=app.MachinesEditField.Value; % Machines
Pmin=app.ProcessTimeMinEditField.Value; % Process time  min
Pmax=app.ProcessTimeMaxEditField.Value; % Process time max
Smin=app.SetupTimeMinEditField.Value; % Setup time min
Smax=app.SetupTimeMaxEditField.Value; % Setup time max
model=CreateModel(I,J,Pmin,Pmax,Smin,Smax);        % Create Model of the Problem

CostFunction=@(x) MyCost(x,model);       % Cost Function
nVar=model.nVar;        % Number of Decision Variables
VarSize=[1 nVar];       % Size of Decision Variables Matrix
VarMin = 0;          % Lower Bound of Decision Variables
VarMax = 1;          % Upper Bound of Decision Variables
            
            %% Firefly Algorithm Parameters

MaxIt = app.IterationsEditField.Value;         % Maximum Number of Iterations
nPop = app.PopulationEditField.Value;           % Population Size

tic

gamma = 1;            % Light Absorption Coefficient
beta0 = 2;            % Attraction Coefficient Base Value
alpha = 0.2;          % Mutation Coefficient
alpha_damp = 0.98;    % Mutation Coefficient Damping Ratio
delta = 0.05*(VarMax-VarMin);     % Uniform Mutation Range
m = 2;

if isscalar(VarMin) && isscalar(VarMax)
dmax = (VarMax-VarMin)*sqrt(nVar);
else
dmax = norm(VarMax-VarMin);
end

%% Start

% Empty Firefly Structure
firefly.Position = [];
firefly.Cost = [];
firefly.Sol = [];

% Initialize Population Array
pop = repmat(firefly, nPop, 1);

% Initialize Best Solution Ever Found
BestSol.Cost = inf;

% Create Initial Fireflies
for i = 1:nPop
pop(i).Position = unifrnd(VarMin, VarMax, VarSize);
[pop(i).Cost pop(i).Sol] = CostFunction(pop(i).Position);

if pop(i).Cost <= BestSol.Cost
BestSol = pop(i);
end
end

% Array to Hold Best Cost Values
BestCost = zeros(MaxIt, 1);

%% Firefly Algorithm Body
for it = 1:MaxIt
newpop = repmat(firefly, nPop, 1);
for i = 1:nPop
newpop(i).Cost = inf;
for j = 1:nPop
if pop(j).Cost < pop(i).Cost
rij = norm(pop(i).Position-pop(j).Position)/dmax;
beta = beta0*exp(-gamma*rij^m);
e = delta*unifrnd(-1, +1, VarSize);
%e = delta*randn(VarSize);

newsol.Position = pop(i).Position ...
+ beta*rand(VarSize).*(pop(j).Position-pop(i).Position) ...
+ alpha*e;

newsol.Position = max(newsol.Position, VarMin);
newsol.Position = min(newsol.Position, VarMax);

[newsol.Cost newsol.Sol] = CostFunction(newsol.Position);

if newsol.Cost <= newpop(i).Cost
newpop(i) = newsol;
if newpop(i).Cost <= BestSol.Cost
BestSol = newpop(i);
end
end
end
end
end

% Merge
pop = [pop
newpop];  

% Sort
[~, SortOrder] = sort([pop.Cost]);
pop = pop(SortOrder);

% Truncate
pop = pop(1:nPop);

% Store Best Cost Ever Found
BestCost(it) = BestSol.Cost;
% Store NFE
nfe(it)=NFE;
% Show Iteration Information
disp(['Iteration ' num2str(it) ': Best Cost = ' num2str(BestCost(it))]);
app.IterationNumberEditField.Value=double(it);
pause(0.000000001);
drawnow;
end

toc
time=toc;
%% Show Results

plot(app.UIAxes,BestCost,'k','LineWidth', 2);
app.TimeEditField.Value=time;
app.CostEditField.Value=double(BestCost(end));

figure(1);
PlotSolution(BestSol.Sol,model);

        end

        % Callback function
        function LoadButton_2Pushed(app, event)

        end

        % Callback function
        function LoadButton_3Pushed(app, event)

        end

        % Callback function
        function ButtonPushed(app, event)

        end

        % Callback function
        function ScatterButtonPushed2(app, event)

        end

        % Button pushed function: HSButton
        function HSButtonPushed(app, event)

global NFE;
NFE=0;

%% Problem Definition
I=app.TasksEditField.Value; % Tasks
J=app.MachinesEditField.Value; % Machines
Pmin=app.ProcessTimeMinEditField.Value; % Process time  min
Pmax=app.ProcessTimeMaxEditField.Value; % Process time max
Smin=app.SetupTimeMinEditField.Value; % Setup time min
Smax=app.SetupTimeMaxEditField.Value; % Setup time max
model=CreateModel(I,J,Pmin,Pmax,Smin,Smax);        % Create Model of the Problem

CostFunction=@(x) MyCost(x,model);       % Cost Function
nVar=model.nVar;        % Number of Decision Variables
VarSize=[1 nVar];       % Size of Decision Variables Matrix
VarMin = 0;          % Lower Bound of Decision Variables
VarMax = 1;          % Upper Bound of Decision Variables

%% Harmony Search Parameters
MaxIt = app.IterationsEditField.Value;         % Maximum Number of Iterations
HMS = app.PopulationEditField.Value;         % Harmony Memory Size

nNew = 5;        % Number of New Harmonies

HMCR = 0.9;       % Harmony Memory Consideration Rate
PAR = 0.1;        % Pitch Adjustment Rate
FW = 0.02*(VarMax-VarMin);    % Fret Width (Bandwidth)
FW_damp = 0.995;              % Fret Width Damp Ratio

tic

%% Start
% Empty Harmony Structure
empty_harmony.Position = [];
empty_harmony.Cost = [];
empty_harmony.Sol = [];

% Initialize Harmony Memory
HM = repmat(empty_harmony, HMS, 1);
% Create Initial Harmonies
for i = 1:HMS
HM(i).Position = unifrnd(VarMin, VarMax, VarSize);
[HM(i).Cost HM(i).Sol] = CostFunction(HM(i).Position);
end
% Sort Harmony Memory
[~, SortOrder] = sort([HM.Cost]);
HM = HM(SortOrder);
% Update Best Solution Ever Found
BestSol = HM(1);
% Array to Hold Best Cost Values
BestCost = zeros(MaxIt, 1);

%% Harmony Search Body
for it = 1:MaxIt
% Initialize Array for New Harmonies
NEW = repmat(empty_harmony, nNew, 1);
% Create New Harmonies
for k = 1:nNew
% Create New Harmony Position
NEW(k).Position = unifrnd(VarMin, VarMax, VarSize);
for j = 1:nVar
if rand <= HMCR
% Use Harmony Memory
i = randi([1 HMS]);
NEW(k).Position(j) = HM(i).Position(j);
end
% Pitch Adjustment
if rand <= PAR
%DELTA = FW*unifrnd(-1, +1);    % Uniform
DELTA = FW*randn();            % Gaussian (Normal) 
NEW(k).Position(j) = NEW(k).Position(j)+DELTA;
end
end
% Apply Variable Limits
NEW(k).Position = max(NEW(k).Position, VarMin);
NEW(k).Position = min(NEW(k).Position, VarMax);
% Evaluation
[NEW(k).Cost NEW(k).Sol] = CostFunction(NEW(k).Position);
end
% Merge Harmony Memory and New Harmonies
HM = [HM
NEW]; 
% Sort Harmony Memory
[~, SortOrder] = sort([HM.Cost]);
HM = HM(SortOrder);
% Truncate Extra Harmonies
HM = HM(1:HMS);
% Update Best Solution Ever Found
BestSol = HM(1);
% Store Best Cost Ever Found
BestCost(it) = BestSol.Cost;
% Store NFE
nfe(it)=NFE;

% Iteration 
disp(['In Iteration ' num2str(it) ': NFE = ' num2str(nfe(it)) ', Cost is = ' num2str(BestCost(it))]);
app.IterationNumberEditField.Value=double(it);
pause(0.000000001);
drawnow;
end

toc
time=toc;
%% Show Results

plot(app.UIAxes,BestCost,'k','LineWidth', 2);
app.TimeEditField.Value=time;
app.CostEditField.Value=double(BestCost(end));

figure(1);
PlotSolution(BestSol.Sol,model);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Parallel Machine Scheduling';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cost')
            xlabel(app.UIAxes, 'Iterations')
            ylabel(app.UIAxes, 'Cost Value')
            zlabel(app.UIAxes, 'Z')
            app.DEButton = uibutton(app.UIFigure, 'push');
            app.DEButton.ButtonPushedFcn = createCallbackFcn(app, @DEButtonPushed, true);
            app.DEButton.Text = 'DE';
            app.IterationsEditFieldLabel = uilabel(app.UIFigure);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationsEditField.Value = 50;
            app.PopulationEditFieldLabel = uilabel(app.UIFigure);
            app.PopulationEditFieldLabel.Text = 'Population';
            app.PopulationEditField = uieditfield(app.UIFigure, 'numeric');
            app.PopulationEditField.Value = 20;
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.CostEditFieldLabel = uilabel(app.UIFigure);
            app.CostEditFieldLabel.Text = 'Cost';
            app.CostEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationNumberEditFieldLabel = uilabel(app.UIFigure);
            app.IterationNumberEditFieldLabel.Text = 'Iteration Number';
            app.IterationNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.SecondsLabel = uilabel(app.UIFigure);
            app.SecondsLabel.Text = 'Seconds';
            app.UnitsLabel = uilabel(app.UIFigure);
            app.UnitsLabel.Text = 'Units';
            app.TLBOButton = uibutton(app.UIFigure, 'push');
            app.TLBOButton.ButtonPushedFcn = createCallbackFcn(app, @TLBOButtonPushed, true);
            app.TLBOButton.Text = 'TLBO';
            app.FAButton = uibutton(app.UIFigure, 'push');
            app.FAButton.ButtonPushedFcn = createCallbackFcn(app, @FAButtonPushed, true);
            app.FAButton.Text = 'FA';
            app.HSButton = uibutton(app.UIFigure, 'push');
            app.HSButton.ButtonPushedFcn = createCallbackFcn(app, @HSButtonPushed, true);
            app.HSButton.Text = 'HS';
            app.ProcessTimeMinEditFieldLabel = uilabel(app.UIFigure);
            app.ProcessTimeMinEditFieldLabel.Text = 'Process Time Min';
            app.ProcessTimeMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProcessTimeMinEditField.Value = 10;
            app.ProcessTimeMaxEditFieldLabel = uilabel(app.UIFigure);
            app.ProcessTimeMaxEditFieldLabel.Text = 'Process Time Max';
            app.ProcessTimeMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProcessTimeMaxEditField.Value = 20;
            app.SetupTimeMinEditFieldLabel = uilabel(app.UIFigure);
            app.SetupTimeMinEditFieldLabel.Text = 'Setup Time Min';
            app.SetupTimeMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetupTimeMinEditField.Value = 3;
            app.SetupTimeMaxEditFieldLabel = uilabel(app.UIFigure);
            app.SetupTimeMaxEditFieldLabel.Text = 'Setup Time Max';
            app.SetupTimeMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetupTimeMaxEditField.Value = 9;
            app.MachinesEditFieldLabel = uilabel(app.UIFigure);
            app.MachinesEditFieldLabel.Text = 'Machines';
            app.MachinesEditField = uieditfield(app.UIFigure, 'numeric');
            app.MachinesEditField.Value = 5;
            app.TasksEditFieldLabel = uilabel(app.UIFigure);
            app.TasksEditFieldLabel.Text = 'Tasks';
            app.TasksEditField = uieditfield(app.UIFigure, 'numeric');
            app.TasksEditField.Value = 20;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PMS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end