classdef Regression < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label                          matlab.ui.control.Label
        TrainerListBox                 matlab.ui.control.ListBox
        TrainerListBoxLabel            matlab.ui.control.Label
        NoofFeaturesEditField          matlab.ui.control.NumericEditField
        NoofFeaturesEditFieldLabel     matlab.ui.control.Label
        Feature2EditField              matlab.ui.control.NumericEditField
        Feature2EditFieldLabel         matlab.ui.control.Label
        Feature1EditField              matlab.ui.control.NumericEditField
        Feature1EditFieldLabel         matlab.ui.control.Label
        ScatterButton                  matlab.ui.control.Button
        ErrSTDEditField                matlab.ui.control.NumericEditField
        ErrSTDEditFieldLabel           matlab.ui.control.Label
        ErrMeanEditField               matlab.ui.control.NumericEditField
        ErrMeanEditFieldLabel          matlab.ui.control.Label
        CCEditField                    matlab.ui.control.NumericEditField
        CCEditFieldLabel               matlab.ui.control.Label
        REditField                     matlab.ui.control.NumericEditField
        REditFieldLabel                matlab.ui.control.Label
        RMSEEditField                  matlab.ui.control.NumericEditField
        RMSEEditFieldLabel             matlab.ui.control.Label
        MSEEditField                   matlab.ui.control.NumericEditField
        MSEEditFieldLabel              matlab.ui.control.Label
        TLBOButton                     matlab.ui.control.Button
        UnitsLabel                     matlab.ui.control.Label
        SecondsLabel                   matlab.ui.control.Label
        IterationNumberEditField       matlab.ui.control.NumericEditField
        IterationNumberEditFieldLabel  matlab.ui.control.Label
        CostEditField                  matlab.ui.control.NumericEditField
        CostEditFieldLabel             matlab.ui.control.Label
        TimeEditField                  matlab.ui.control.NumericEditField
        TimeEditFieldLabel             matlab.ui.control.Label
        PopulationEditField            matlab.ui.control.NumericEditField
        PopulationEditFieldLabel       matlab.ui.control.Label
        IterationsEditField            matlab.ui.control.NumericEditField
        IterationsEditFieldLabel       matlab.ui.control.Label
        NeuronsEditField               matlab.ui.control.NumericEditField
        NeuronsEditFieldLabel          matlab.ui.control.Label
        DEButton                       matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end


methods (Access = private)
function updatefile(app,file)
dat = load(file);
global dat;
end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Callback function
        function DropDownValueChanged(app, event)

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)

% Display uigetfile dialog
filterspec = {'*.csv'};
[f, p] = uigetfile(filterspec);

% Make sure user didn't cancel uigetfile dialog
if (ischar(p))
fname = [p f];
updatefile(app, fname);
end


        end

        % Button pushed function: DEButton
        function DEButtonPushed(app, event)
global dat;
Inputs=dat(:,1:end-1);
Targets=dat(:,end);

%% Learning 

n = app.NeuronsEditField.Value; % Neurons
tic

net = feedforwardnet(n,app.TrainerListBox.Value);

%---------------------------------------------
% configure the neural network for this dataset
[net tr]= train(net,Inputs', Targets');

perf = perform(net,Inputs, Targets); % mse
% Current NN Weights and Bias
Weights_Bias = getwb(net);
% MSE Error for Current NN
Outputs=net(Inputs');
Outputs=Outputs';
% Final MSE Error and Correlation Coefficients (CC)
Err_MSE=mse(Targets,Outputs);
CC1= corrcoef(Targets,Outputs);
CC1= CC1(1,2);

%-----------------------------------------------------
%% Nature Inspired Regression
% Create Handle for Error
h = @(x) MSEHandle(x, net, Inputs', Targets');

sizenn=size(Inputs);
sizenn=sizenn(1,1);
%-----------------------------------------

% Variables 
VarSize = [1 sizenn*n+n+n+1];   % Decision Variables Matrix Size
VarMin = -5;         % Decision Variables Lower Bound
VarMax = 5;         % Decision Variables Upper Bound

% DE Parameters
MaxIt = app.IterationsEditField.Value;       % Maximum Number of Iterations
nPop = app.PopulationEditField.Value;        % Population Size 

beta_min = 0.2;   % Lower Bound of Scaling Factor
beta_max = 0.8;   % Upper Bound of Scaling Factor
pCR = 0.2;        % Crossover Probability

%% Start
CostFunction=h;

empty_individual.Position = [];
empty_individual.Cost = [];
BestSol.Cost = inf;
pop = repmat(empty_individual, nPop, 1);
for i = 1:nPop
pop(i).Position = unifrnd(VarMin, VarMax, VarSize);
pop(i).Cost= CostFunction(pop(i).Position);
if pop(i).Cost<BestSol.Cost
BestSol = pop(i);
end
end
BestCost = zeros(MaxIt, 1);

%% DE Body

for it = 1:MaxIt
for i = 1:nPop
x = pop(i).Position;
A = randperm(nPop);
A(A == i) = [];
a = A(1);
b = A(2);
c = A(3);
% Mutation
%beta = unifrnd(beta_min, beta_max);
beta = unifrnd(beta_min, beta_max, VarSize);
y = pop(a).Position+beta.*(pop(b).Position-pop(c).Position);
y = max(y, VarMin);
y = min(y, VarMax);
% Crossover
z = zeros(size(x));
j0 = randi([1 numel(x)]);
for j = 1:numel(x)
if j == j0 || rand <= pCR
z(j) = y(j);
else
z(j) = x(j);
end
end
NewSol.Position = z;
NewSol.Cost= CostFunction(NewSol.Position);
if NewSol.Cost<pop(i).Cost
pop(i) = NewSol;
if pop(i).Cost<BestSol.Cost
BestSol = pop(i);
end
end
end
% Update Best Cost
BestCost(it) = BestSol.Cost;
% Show Iteration Information
disp(['In Iteration ' num2str(it) ': DE Cost Is = ' num2str(BestCost(it))]);
app.IterationNumberEditField.Value=double(it);
pause(0.000000001);
drawnow;
end

x=BestSol.Position';
err=BestSol.Cost;


%%-------------------------------------------------------------------------
net = setwb(net, x');
% Optimized NN Weights and Bias
getwb(net);
% Error for Optimized NN
Outputs2=net(Inputs');
Outputs2=Outputs2';
% Final MSE Error and Correlation Coefficients (CC)
Err_MSE2=mse(Targets,Outputs2);
CC2= corrcoef(Targets,Outputs2);
CC2= CC2(1,2);

toc
time=toc;
plot(app.UIAxes,BestCost,'k','LineWidth', 2);
app.TimeEditField.Value=time;
app.CostEditField.Value=double(BestCost(end));

%% Plot Regression
f = figure;  
f.Position = [100 100 700 550]; 
% Metaheuristics
subplot(3,1,1)
[population3,gof3] = fit(Targets,Outputs2,'poly3');
plot(Targets,Outputs2,'o',...
'LineWidth',1,...
'MarkerSize',8,...
'MarkerEdgeColor','g',...
'MarkerFaceColor',[0.9,0.3,0.1]);
title(['R =  ' num2str(1-gof3.rmse)],['MSE =  ' num2str(Err_MSE2)]); 
hold on
plot(population3,'b-','predobs');
xlabel('Targets');ylabel('Outputs');   grid on;
ax = gca; 
ax.FontSize = 12; ax.LineWidth=2;
% legend({'Regression'},'FontSize',12,'TextColor','blue');hold off
subplot(3,1,2)
% Error
Errors=Targets-Outputs2;
ErrorMean=mean(Errors);
ErrorStd=std(Errors);
subplot(3,1,2);
plot(Targets,'m');hold on;
plot(Outputs2,'k');legend('Target','Output');
title('Training Part');xlabel('Sample Index');grid on;
subplot(3,1,3);
h=histfit(Errors, 50);
h(1).FaceColor = [.3 .8 0.3];
title(['Train Error Mean =    ' num2str(ErrorMean) '   ,' ...
    '   Train Error STD =    ' num2str(ErrorStd)]);grid on;
app.MSEEditField.Value=Err_MSE2;
app.RMSEEditField.Value=sqrt(Err_MSE2);
app.REditField.Value=1-gof3.rmse;
app.CCEditField.Value=CC2;
app.ErrMeanEditField.Value=ErrorMean;
app.ErrSTDEditField.Value=ErrorStd;

        end

        % Button pushed function: TLBOButton
        function TLBOButtonPushed(app, event)
global dat;
Inputs=dat(:,1:end-1);
Targets=dat(:,end);

%% Learning 

n = app.NeuronsEditField.Value; % Neurons
tic
% Creating the NN ----------------------------

net = feedforwardnet(n,app.TrainerListBox.Value);

%---------------------------------------------
% configure the neural network for this dataset
[net tr]= train(net,Inputs', Targets');

perf = perform(net,Inputs, Targets); % mse
% Current NN Weights and Bias
Weights_Bias = getwb(net);
% MSE Error for Current NN
Outputs=net(Inputs');
Outputs=Outputs';
% Final MSE Error and Correlation Coefficients (CC)
Err_MSE=mse(Targets,Outputs);
CC1= corrcoef(Targets,Outputs);
CC1= CC1(1,2);

%-----------------------------------------------------
%% Nature Inspired Regression
% Create Handle for Error
h = @(x) MSEHandle(x, net, Inputs', Targets');

sizenn=size(Inputs);
sizenn=sizenn(1,1);
%-----------------------------------------
%% Start
MaxIt = app.IterationsEditField.Value;       % Maximum Number of Iterations
nPop = app.PopulationEditField.Value;        % Population Size 

%% Variables 
VarSize = [1 sizenn*n+n+n+1];   % Decision Variables Matrix Size
VarMin = -5;         % Decision Variables Lower Bound
VarMax = 5;         % Decision Variables Upper Bound

%% Start 
CostFunction=h;

% Empty Individuals
empty_individual.Position = [];
empty_individual.Cost = [];

% Population Array
pop = repmat(empty_individual, nPop, 1);
% Initialize Best Solution
BestSol.Cost = inf;
% Population Members
for i = 1:nPop
pop(i).Position = unifrnd(VarMin, VarMax, VarSize);
pop(i).Cost = CostFunction(pop(i).Position);
if pop(i).Cost < BestSol.Cost
BestSol = pop(i);
end
end
% Best Cost Record
BestCost = zeros(MaxIt, 1);

%% TLBO Body
for it = 1:MaxIt
% Calculate Population Mean
Mean = 0;
for i = 1:nPop
Mean = Mean + pop(i).Position;
end
Mean = Mean/nPop;
% Select Teacher
Teacher = pop(1);
for i = 2:nPop
if pop(i).Cost < Teacher.Cost
Teacher = pop(i);
end
end
% Teacher Phase
for i = 1:nPop
% Create Empty Solution
newsol = empty_individual;
% Teaching Factor
TF = randi([1 2]);
% Teaching (moving towards teacher)
newsol.Position = pop(i).Position ...
+ rand(VarSize).*(Teacher.Position - TF*Mean);
% Clipping
newsol.Position = max(newsol.Position, VarMin);
newsol.Position = min(newsol.Position, VarMax);
% Evaluation
newsol.Cost = CostFunction(newsol.Position);
% Comparision
if newsol.Cost<pop(i).Cost
pop(i) = newsol;
if pop(i).Cost < BestSol.Cost
BestSol = pop(i);
end
end
end
% Learner Phase
for i = 1:nPop
A = 1:nPop;
A(i) = [];
j = A(randi(nPop-1));
Step = pop(i).Position - pop(j).Position;
if pop(j).Cost < pop(i).Cost
Step = -Step;
end
% Create Empty Solution
newsol = empty_individual;
% Teaching (moving towards teacher)
newsol.Position = pop(i).Position + rand(VarSize).*Step;
% Clipping
newsol.Position = max(newsol.Position, VarMin);
newsol.Position = min(newsol.Position, VarMax);
% Evaluation
newsol.Cost = CostFunction(newsol.Position);
% Comparision
if newsol.Cost<pop(i).Cost
pop(i) = newsol;
if pop(i).Cost < BestSol.Cost
BestSol = pop(i);
end
end
end
BestCost(it) = BestSol.Cost;
% Iteration 
disp(['In Iteration ' num2str(it) ': TLBO Cost Is = ' num2str(BestCost(it))]);
app.IterationNumberEditField.Value=double(it);
pause(0.000000001);
drawnow;
end

x=BestSol.Position';
err=BestSol.Cost;

%%-------------------------------------------------------------------------
net = setwb(net, x');
% Optimized NN Weights and Bias
getwb(net);
% Error for Optimized NN
Outputs2=net(Inputs');
Outputs2=Outputs2';
% Final MSE Error and Correlation Coefficients (CC)
Err_MSE2=mse(Targets,Outputs2);
CC2= corrcoef(Targets,Outputs2);
CC2= CC2(1,2);

toc
time=toc;
plot(app.UIAxes,BestCost,'k','LineWidth', 2);
app.TimeEditField.Value=time;
app.CostEditField.Value=double(BestCost(end));

%% Plot Regression
f = figure;  
f.Position = [100 100 700 550]; 
% Metaheuristics
subplot(3,1,1)
[population3,gof3] = fit(Targets,Outputs2,'poly3');
plot(Targets,Outputs2,'o',...
'LineWidth',1,...
'MarkerSize',8,...
'MarkerEdgeColor','g',...
'MarkerFaceColor',[0.9,0.3,0.1]);
title(['R =  ' num2str(1-gof3.rmse)],['MSE =  ' num2str(Err_MSE2)]); 
hold on
plot(population3,'b-','predobs');
xlabel('Targets');ylabel('Outputs');   grid on;
ax = gca; 
ax.FontSize = 12; ax.LineWidth=2;
% legend({'Regression'},'FontSize',12,'TextColor','blue');hold off
subplot(3,1,2)
% Error
Errors=Targets-Outputs2;
ErrorMean=mean(Errors);
ErrorStd=std(Errors);
subplot(3,1,2);
plot(Targets,'m');hold on;
plot(Outputs2,'k');legend('Target','Output');
title('Training Part');xlabel('Sample Index');grid on;
subplot(3,1,3);
h=histfit(Errors, 50);
h(1).FaceColor = [.3 .8 0.3];
title(['Train Error Mean =    ' num2str(ErrorMean) '   ,' ...
    '   Train Error STD =    ' num2str(ErrorStd)]);grid on;
app.MSEEditField.Value=Err_MSE2;
app.RMSEEditField.Value=sqrt(Err_MSE2);
app.REditField.Value=1-gof3.rmse;
app.CCEditField.Value=CC2;
app.ErrMeanEditField.Value=ErrorMean;
app.ErrSTDEditField.Value=ErrorStd;


        end

        % Callback function
        function FAButtonPushed(app, event)
global dat;
Inputs=dat(:,1:end-1);
Targets=dat(:,end);

%% Learning 

n = app.NeuronsEditField.Value; % Neurons
tic

net = feedforwardnet(n,app.TrainerListBox.Value);

%---------------------------------------------
% configure the neural network for this dataset
[net tr]= train(net,Inputs', Targets');

perf = perform(net,Inputs, Targets); % mse
% Current NN Weights and Bias
Weights_Bias = getwb(net);
% MSE Error for Current NN
Outputs=net(Inputs');
Outputs=Outputs';
% Final MSE Error and Correlation Coefficients (CC)
Err_MSE=mse(Targets,Outputs);
CC1= corrcoef(Targets,Outputs);
CC1= CC1(1,2);

%-----------------------------------------------------
%% Nature Inspired Regression
% Create Handle for Error
h = @(x) MSEHandle(x, net, Inputs', Targets');

sizenn=size(Inputs);
sizenn=sizenn(1,1);
%-----------------------------------------

% Variables 
VarSize = [1 sizenn*n+n+n+1];   % Decision Variables Matrix Size
VarMin = -5;         % Decision Variables Lower Bound
VarMax = 5;         % Decision Variables Upper Bound

%% Firefly Algorithm Parameters

MaxIt = app.IterationsEditField.Value;       % Maximum Number of Iterations
nPop = app.PopulationEditField.Value;        % Population Size 

gamma = 2;            % Light Absorption Coefficient
beta0 = 4;            % Attraction Coefficient Base Value
alpha = 0.2;          % Mutation Coefficient
alpha_damp = 0.98;    % Mutation Coefficient Damping Ratio
delta = 0.05*(VarMax-VarMin);     % Uniform Mutation Range
m = 2;
if isscalar(VarMin) && isscalar(VarMax)
dmax = (VarMax-VarMin)*sqrt(nVar);
else
dmax = norm(VarMax-VarMin);
end

%% Start
CostFunction=h;


% Empty Firefly Structure
firefly.Position = [];
firefly.Cost = [];
% Initialize Population Array
pop = repmat(firefly, nPop, 1);
% Initialize Best Solution Ever Found
BestSol.Cost = inf;
% Create Initial Fireflies
for i = 1:nPop
pop(i).Position = unifrnd(VarMin, VarMax, VarSize);
pop(i).Cost = CostFunction(pop(i).Position);
if pop(i).Cost <= BestSol.Cost
BestSol = pop(i);
end
end
% Array to Hold Best Cost Values
BestCost = zeros(MaxIt, 1);

%% Firefly Algorithm Body

for it = 1:MaxIt
newpop = repmat(firefly, nPop, 1);
for i = 1:nPop
newpop(i).Cost = inf;
for j = 1:nPop
if pop(j).Cost < pop(i).Cost
rij = norm(pop(i).Position-pop(j).Position)/dmax;
beta = beta0*exp(-gamma*rij^m);
e = delta*unifrnd(-1, +1, VarSize);
newsol.Position = pop(i).Position ...
+ beta*rand(VarSize).*(pop(j).Position-pop(i).Position) ...
+ alpha*e;
newsol.Position = max(newsol.Position, VarMin);
newsol.Position = min(newsol.Position, VarMax);
newsol.Cost = CostFunction(newsol.Position);
if newsol.Cost <= newpop(i).Cost
newpop(i) = newsol;
if newpop(i).Cost <= BestSol.Cost
BestSol = newpop(i);
end
end
end
end
end
% Merge
pop = [pop
newpop];
% Sort
[~, SortOrder] = sort([pop.Cost]);
pop = pop(SortOrder);
% Truncate
pop = pop(1:nPop);
% Store Best Cost Ever Found
BestCost(it) = BestSol.Cost;
% Show Iteration Information
disp(['In Iteration ' num2str(it) ': FA Cost Is = ' num2str(BestCost(it))]);
% Damp Mutation Coefficient
alpha = alpha*alpha_damp;
app.IterationNumberEditField.Value=double(it);
pause(0.000000001);
drawnow;
end

x=BestSol.Position';
err=BestSol.Cost;

%%-------------------------------------------------------------------------
net = setwb(net, x');
% Optimized NN Weights and Bias
getwb(net);
% Error for Optimized NN
Outputs2=net(Inputs');
Outputs2=Outputs2';
% Final MSE Error and Correlation Coefficients (CC)
Err_MSE2=mse(Targets,Outputs2);
CC2= corrcoef(Targets,Outputs2);
CC2= CC2(1,2);

toc
time=toc;
plot(app.UIAxes,BestCost,'k','LineWidth', 2);
app.TimeEditField.Value=time;
app.CostEditField.Value=double(BestCost(end));

%% Plot Regression
f = figure;  
f.Position = [100 100 700 550]; 
% Metaheuristics
subplot(3,1,1)
[population3,gof3] = fit(Targets,Outputs2,'poly3');
plot(Targets,Outputs2,'o',...
'LineWidth',1,...
'MarkerSize',8,...
'MarkerEdgeColor','g',...
'MarkerFaceColor',[0.9,0.3,0.1]);
title(['R =  ' num2str(1-gof3.rmse)],['MSE =  ' num2str(Err_MSE2)]); 
hold on
plot(population3,'b-','predobs');
xlabel('Targets');ylabel('Outputs');   grid on;
ax = gca; 
ax.FontSize = 12; ax.LineWidth=2;
% legend({'Regression'},'FontSize',12,'TextColor','blue');hold off
subplot(3,1,2)
% Error
Errors=Targets-Outputs2;
ErrorMean=mean(Errors);
ErrorStd=std(Errors);
subplot(3,1,2);
plot(Targets,'m');hold on;
plot(Outputs2,'k');legend('Target','Output');
title('Training Part');xlabel('Sample Index');grid on;
subplot(3,1,3);
h=histfit(Errors, 50);
h(1).FaceColor = [.3 .8 0.3];
title(['Train Error Mean =    ' num2str(ErrorMean) '   ,' ...
    '   Train Error STD =    ' num2str(ErrorStd)]);grid on;
app.MSEEditField.Value=Err_MSE2;
app.RMSEEditField.Value=sqrt(Err_MSE2);
app.REditField.Value=1-gof3.rmse;
app.CCEditField.Value=CC2;
app.ErrMeanEditField.Value=ErrorMean;
app.ErrSTDEditField.Value=ErrorStd;

        end

        % Callback function
        function LoadButton_2Pushed(app, event)
            % Display uigetfile dialog
filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
[f, p] = uigetfile(filterspec);

% Make sure user didn't cancel uigetfile dialog
if (ischar(p))
fname = [p f];
updateimage2(app, fname);
end


        end

        % Callback function
        function LoadButton_3Pushed(app, event)

        end

        % Callback function
        function ButtonPushed(app, event)

        end

        % Callback function
        function ScatterButtonPushed2(app, event)

        end

        % Button pushed function: ScatterButton
        function ScatterButtonPushed3(app, event)
                      global dat;
mydat=dat(:,1:end-1);
fone=app.Feature1EditField.Value;
ftwo=app.Feature2EditField.Value;
plot(app.UIAxes_2,mydat(:,fone),mydat(:,ftwo),'o',...
'LineWidth',2,...
'MarkerSize',8,...
'MarkerEdgeColor','b',...
'MarkerFaceColor',[0.2,0.9,0.3]);

sizemydat=size(mydat);
sizemydat=sizemydat(1,2);
app.NoofFeaturesEditField.Value=sizemydat;


        end

        % Callback function
        function HSButtonPushed(app, event)
global dat;
datp=dat;
Inputs=datp(:,1:end-1);
Targets=datp(:,end);

%% Learning 

n = app.NeuronsEditField.Value; % Neurons

% tic

net = feedforwardnet(n,app.TrainerListBox.Value);

%---------------------------------------------
% configure the neural network for this dataset
[net tr]= train(net,Inputs', Targets');

perf = perform(net,Inputs, Targets); % mse
% Current NN Weights and Bias
Weights_Bias = getwb(net);
% MSE Error for Current NN
Outputs=net(Inputs');
Outputs=Outputs';
% Final MSE Error and Correlation Coefficients (CC)
Err_MSE=mse(Targets,Outputs);
CC1= corrcoef(Targets,Outputs);
CC1= CC1(1,2);

%-----------------------------------------------------
%% Nature Inspired Regression
% Create Handle for Error
h = @(x) MSEHandle(x, net, Inputs', Targets');

sizenn=size(Inputs);
sizenn=sizenn(1,1);
%-----------------------------------------

% Variables 
VarSize = [1 sizenn*n+n+n+1];   % Decision Variables Matrix Size
VarMin = -5;         % Decision Variables Lower Bound
VarMax = 5;         % Decision Variables Upper Bound

%% Harmony Search Parameters

MaxIt = app.IterationsEditField.Value;     % Maximum Number of Iterations
HMS = app.PopulationEditField.Value;         % Harmony Memory Size
nNew = 5;        % Number of New Harmonies

HMCR = 0.9;       % Harmony Memory Consideration Rate
PAR = 0.1;        % Pitch Adjustment Rate
FW = 0.02*(VarMax-VarMin);    % Fret Width (Bandwidth)
FW_damp = 0.995;              % Fret Width Damp Ratio

%% Start
CostFunction=h;

% Empty Harmony Structure
empty_harmony.Position = [];
empty_harmony.Cost = [];
% Initialize Harmony Memory
HM = repmat(empty_harmony, HMS, 1);
% Create Initial Harmonies
for i = 1:HMS
HM(i).Position = unifrnd(VarMin, VarMax, VarSize);
HM(i).Cost = CostFunction(HM(i).Position);
end
% Sort Harmony Memory
[~, SortOrder] = sort([HM.Cost]);
HM = HM(SortOrder);
% Update Best Solution Ever Found
BestSol = HM(1);
% Array to Hold Best Cost Values
BestCost = zeros(MaxIt, 1);

%% Harmony Search Body

for it = 1:MaxIt
% Initialize Array for New Harmonies
NEW = repmat(empty_harmony, nNew, 1);
% Create New Harmonies
for k = 1:nNew
% Create New Harmony Position
NEW(k).Position = unifrnd(VarMin, VarMax, VarSize);
for j = 1:nVar
if rand <= HMCR
% Use Harmony Memory
i = randi([1 HMS]);
NEW(k).Position(j) = HM(i).Position(j);
end
% Pitch Adjustment
if rand <= PAR
%DELTA = FW*unifrnd(-1, +1);    % Uniform
DELTA = FW*randn();            % Gaussian (Normal) 
NEW(k).Position(j) = NEW(k).Position(j)+DELTA;
end
end
% Apply Variable Limits
NEW(k).Position = max(NEW(k).Position, VarMin);
NEW(k).Position = min(NEW(k).Position, VarMax);
% Evaluation
NEW(k).Cost = CostFunction(NEW(k).Position);
end
% Merge Harmony Memory and New Harmonies
HM = [HM
NEW]; %#ok
% Sort Harmony Memory
[~, SortOrder] = sort([HM.Cost]);
HM = HM(SortOrder);
% Truncate Extra Harmonies
HM = HM(1:HMS);
% Update Best Solution Ever Found
BestSol = HM(1);
% Store Best Cost Ever Found
BestCost(it) = BestSol.Cost;
% Show Iteration Information
disp(['In Iteration ' num2str(it) ': HS Cost Is = ' num2str(BestCost(it))]);
% Damp Fret Width
FW = FW*FW_damp;
end

% x=BestSol.Position';
% err=BestSol.Cost;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Regression';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cost')
            xlabel(app.UIAxes, 'Iterations')
            ylabel(app.UIAxes, 'Cost Value')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Data Scattering')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load CSV';
            app.DEButton = uibutton(app.UIFigure, 'push');
            app.DEButton.ButtonPushedFcn = createCallbackFcn(app, @DEButtonPushed, true);
            app.DEButton.Text = 'DE';
            app.NeuronsEditFieldLabel = uilabel(app.UIFigure);
            app.NeuronsEditFieldLabel.Text = 'Neurons';
            app.NeuronsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NeuronsEditField.Value = 9;
            app.IterationsEditFieldLabel = uilabel(app.UIFigure);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationsEditField.Value = 5;
            app.PopulationEditFieldLabel = uilabel(app.UIFigure);
            app.PopulationEditFieldLabel.Text = 'Population';
            app.PopulationEditField = uieditfield(app.UIFigure, 'numeric');
            app.PopulationEditField.Value = 5;
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.CostEditFieldLabel = uilabel(app.UIFigure);
            app.CostEditFieldLabel.Text = 'Cost';
            app.CostEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationNumberEditFieldLabel = uilabel(app.UIFigure);
            app.IterationNumberEditFieldLabel.Text = 'Iteration Number';
            app.IterationNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.SecondsLabel = uilabel(app.UIFigure);
            app.SecondsLabel.Text = 'Seconds';
            app.UnitsLabel = uilabel(app.UIFigure);
            app.UnitsLabel.Text = 'Units';
            app.TLBOButton = uibutton(app.UIFigure, 'push');
            app.TLBOButton.ButtonPushedFcn = createCallbackFcn(app, @TLBOButtonPushed, true);
            app.TLBOButton.Text = 'TLBO';
            app.MSEEditFieldLabel = uilabel(app.UIFigure);
            app.MSEEditFieldLabel.Text = 'MSE';
            app.MSEEditField = uieditfield(app.UIFigure, 'numeric');
            app.RMSEEditFieldLabel = uilabel(app.UIFigure);
            app.RMSEEditFieldLabel.Text = 'RMSE';
            app.RMSEEditField = uieditfield(app.UIFigure, 'numeric');
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.UIFigure, 'numeric');
            app.CCEditFieldLabel = uilabel(app.UIFigure);
            app.CCEditFieldLabel.Text = 'CC';
            app.CCEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErrMeanEditFieldLabel = uilabel(app.UIFigure);
            app.ErrMeanEditFieldLabel.Text = 'Err Mean';
            app.ErrMeanEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErrSTDEditFieldLabel = uilabel(app.UIFigure);
            app.ErrSTDEditFieldLabel.Text = 'Err STD';
            app.ErrSTDEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScatterButton = uibutton(app.UIFigure, 'push');
            app.ScatterButton.ButtonPushedFcn = createCallbackFcn(app, @ScatterButtonPushed3, true);
            app.ScatterButton.Text = 'Scatter';
            app.Feature1EditFieldLabel = uilabel(app.UIFigure);
            app.Feature1EditFieldLabel.Text = 'Feature 1';
            app.Feature1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Feature1EditField.Value = 1;
            app.Feature2EditFieldLabel = uilabel(app.UIFigure);
            app.Feature2EditFieldLabel.Text = 'Feature 2';
            app.Feature2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Feature2EditField.Value = 2;
            app.NoofFeaturesEditFieldLabel = uilabel(app.UIFigure);
            app.NoofFeaturesEditFieldLabel.Text = 'No of Features';
            app.NoofFeaturesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoofFeaturesEditField.Editable = 'off';
            app.NoofFeaturesEditField.Value = 2;
            app.TrainerListBoxLabel = uilabel(app.UIFigure);
            app.TrainerListBoxLabel.Text = 'Trainer';
            app.TrainerListBox = uilistbox(app.UIFigure);
            app.TrainerListBox.Items = {'trainoss', 'traincgf', 'traingd', 'trainlm', 'trainbr', 'trainrp'};
            app.TrainerListBox.Value = 'trainoss';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Help'; ' ''trainlm''	Levenberg-Marquardt'; ' ''trainbr'' 	Bayesian Regularization '; ' ''trainrp''  	Resilient Backpropagation'; ' ''traincgf''	Fletcher-Powell Conjugate '; ' ''trainoss''	One Step Secant '; ' ''traingd'' 	Gradient Descent'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Regression

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end