classdef TSP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TravellingSalesmanProblemTSPUIFigure  matlab.ui.Figure
        TourTextArea                   matlab.ui.control.TextArea
        TourTextAreaLabel              matlab.ui.control.Label
        UnitsLabel                     matlab.ui.control.Label
        SecondsLabel                   matlab.ui.control.Label
        FAButton                       matlab.ui.control.Button
        HSButton                       matlab.ui.control.Button
        DEButton                       matlab.ui.control.Button
        IterationNumberEditField       matlab.ui.control.NumericEditField
        IterationNumberEditFieldLabel  matlab.ui.control.Label
        TimeEditField                  matlab.ui.control.NumericEditField
        TimeEditFieldLabel             matlab.ui.control.Label
        CostEditField                  matlab.ui.control.NumericEditField
        CostEditFieldLabel             matlab.ui.control.Label
        YTextArea                      matlab.ui.control.TextArea
        YTextAreaLabel                 matlab.ui.control.Label
        LoadCSVButton                  matlab.ui.control.Button
        XTextArea                      matlab.ui.control.TextArea
        XTextAreaLabel                 matlab.ui.control.Label
        PopulationEditField            matlab.ui.control.NumericEditField
        PopulationEditFieldLabel       matlab.ui.control.Label
        IterationsEditField            matlab.ui.control.NumericEditField
        IterationsEditFieldLabel       matlab.ui.control.Label
        TLBOButton                     matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TLBOButton
        function TLBOButtonPushed(app, event)
            % These lines of code, solves the Travelling Salesman Problem (TSP)
% by Teaching Learning Based Optimization Algorithm (TLBO) algorithm. 
% By default, 20 points are made by "MakeModel.m” file and you can 
% change it if you want. 

%% Input Model 

global x;
x=x;
global y;
y=y;

model=MakeModel(x,y);

tic

% Cost
CostFunction=@(s) CostF(s,model);        % Cost Function
nVar=model.n;             % Number of Decision Variables
VarSize=[1 nVar];         % Decision Variables Matrix Size
VarMin=0;                 % Lower Bound of Variables
VarMax=1;                 % Upper Bound of Variables

%% TLBO Parameters
MaxIt = app.IterationsEditField.Value;         % Maximum Number of Iterations
nPop = app.PopulationEditField.Value;           % Population Size

%% Start 
% Empty Individuals
empty_individual.Position = [];
empty_individual.Cost = [];
empty_individual.Sol = [];

% Population Array
pop = repmat(empty_individual, nPop, 1);
% Initialize Best Solution
BestSol.Cost = inf;
% Population Members
for i = 1:nPop
pop(i).Position = unifrnd(VarMin, VarMax, VarSize);
[pop(i).Cost pop(i).Sol] = CostFunction(pop(i).Position);
if pop(i).Cost < BestSol.Cost
BestSol = pop(i);
end
end
% Best Cost Record
BestCost = zeros(MaxIt, 1);

%% TLBO 
for it = 1:MaxIt
% Calculate Population Mean
Mean = 0;
for i = 1:nPop
Mean = Mean + pop(i).Position;
end
Mean = Mean/nPop;
% Select Teacher
Teacher = pop(1);
for i = 2:nPop
if pop(i).Cost < Teacher.Cost
Teacher = pop(i);
end
end

% Teacher Phase
for i = 1:nPop
% Create Empty Solution
newsol = empty_individual;
% Teaching Factor
TF = randi([1 2]);
% Teaching (moving towards teacher)
newsol.Position = pop(i).Position ...
+ rand(VarSize).*(Teacher.Position - TF*Mean);
% Clipping
newsol.Position = max(newsol.Position, VarMin);
newsol.Position = min(newsol.Position, VarMax);
% Evaluation
[newsol.Cost newsol.Sol] = CostFunction(newsol.Position);
% Comparision
if newsol.Cost<pop(i).Cost
pop(i) = newsol;
if pop(i).Cost < BestSol.Cost
BestSol = pop(i);
end
end
end

% Learner Phase
for i = 1:nPop
A = 1:nPop;
A(i) = [];
j = A(randi(nPop-1));
Step = pop(i).Position - pop(j).Position;
if pop(j).Cost < pop(i).Cost
Step = -Step;
end
% Create Empty Solution
newsol = empty_individual;
% Teaching (moving towards teacher)
newsol.Position = pop(i).Position + rand(VarSize).*Step;
% Clipping
newsol.Position = max(newsol.Position, VarMin);
newsol.Position = min(newsol.Position, VarMax);
% Evaluation
[newsol.Cost newsol.Sol] = CostFunction(newsol.Position);
% Comparision
if newsol.Cost<pop(i).Cost
pop(i) = newsol;
if pop(i).Cost < BestSol.Cost
BestSol = pop(i);
end
end
end
BestCost(it) = BestSol.Cost;
% Iteration 
disp(['In ITR ' num2str(it) ': TLBO Cost Value Is = ' num2str(BestCost(it))]);

app.IterationNumberEditField.Value=double(it);
pause(0.000000001);
drawnow;
end

% Time and Cost
toc
time=toc;
app.TimeEditField.Value=double(time);
app.CostEditField.Value=double(BestCost(end));
TourChar=num2str(BestSol.Sol.tour);
app.TourTextArea.Value=TourChar;

%% Res
plot(app.UIAxes,BestCost,'k','LineWidth', 2);
tour=[BestSol.Sol.tour BestSol.Sol.tour(1)];
x=model.x;
y=model.y;
plot(app.UIAxes2,x(tour),y(tour),'-hr',...
'LineWidth',3,...
'MarkerSize',15,...
'MarkerFaceColor',[0.1 0.9 0.2],...
'MarkerEdgeColor','b');


        end

        % Button pushed function: LoadCSVButton
        function LoadCSVButtonPushed(app, event)
      % Display uigetfile dialog

filterspec = {'*.csv'};
[f, p] = uigetfile(filterspec);
fileadd=fullfile(p,f);
filecon=load(fileadd);
x=filecon(1,:);
y=filecon(2,:);
xchar=num2str(x);
ychar=num2str(y);
app.XTextArea.Value=xchar;
app.YTextArea.Value=ychar;
global x;
global y;

        end

        % Button pushed function: DEButton
        function DEButtonPushed(app, event)
            
global x;
x=x;
global y;
y=y;

model=MakeModel(x,y);


tic

CostFunction=@(s) CostF(s,model);        % Cost Function
nVar=model.n;             % Number of Decision Variables
VarSize=[1 nVar];         % Decision Variables Matrix Size
VarMin=0;                 % Lower Bound of Variables
VarMax=1;                 % Upper Bound of Variables


%% DE Parameters

MaxIt = app.IterationsEditField.Value;         % Maximum Number of Iterations
nPop = app.PopulationEditField.Value;           % Population Size

beta_min = 0.2;   % Lower Bound of Scaling Factor
beta_max = 0.8;   % Upper Bound of Scaling Factor

pCR = 0.2;        % Crossover Probability

%% Initialization

empty_individual.Position = [];
empty_individual.Cost = [];
empty_individual.Sol = [];

BestSol.Cost = inf;

pop = repmat(empty_individual, nPop, 1);

for i = 1:nPop

pop(i).Position = unifrnd(VarMin, VarMax, VarSize);

[pop(i).Cost pop(i).Sol] = CostFunction(pop(i).Position);

if pop(i).Cost<BestSol.Cost
BestSol = pop(i);
end

end

BestCost = zeros(MaxIt, 1);

%% DE Main Loop

for it = 1:MaxIt

for i = 1:nPop

x = pop(i).Position;

A = randperm(nPop);

A(A == i) = [];

a = A(1);
b = A(2);
c = A(3);

% Mutation
%beta = unifrnd(beta_min, beta_max);
beta = unifrnd(beta_min, beta_max, VarSize);
y = pop(a).Position+beta.*(pop(b).Position-pop(c).Position);
y = max(y, VarMin);
y = min(y, VarMax);

% Crossover
z = zeros(size(x));
j0 = randi([1 numel(x)]);
for j = 1:numel(x)
if j == j0 || rand <= pCR
z(j) = y(j);
else
z(j) = x(j);
end
end

NewSol.Position = z;
[NewSol.Cost NewSol.Sol]= CostFunction(NewSol.Position);

if NewSol.Cost<pop(i).Cost
pop(i) = NewSol;

if pop(i).Cost<BestSol.Cost
BestSol = pop(i);
end
end

end

% Update Best Cost
BestCost(it) = BestSol.Cost;

% Show Iteration Information
disp(['Iteration ' num2str(it) ': Best Cost = ' num2str(BestCost(it))]);

% Plot Res
% figure(1);
% Plotfig(BestSol.Sol.tour,model);
app.IterationNumberEditField.Value=double(it);
pause(0.000000001);
drawnow;
end

% Time and Cost
toc
time=toc
app.TimeEditField.Value=double(time);
app.CostEditField.Value=double(BestCost(end));
TourChar=num2str(BestSol.Sol.tour);
app.TourTextArea.Value=TourChar;

%% Res
plot(app.UIAxes,BestCost,'k','LineWidth', 2);
tour=[BestSol.Sol.tour BestSol.Sol.tour(1)];
x=model.x;
y=model.y;
plot(app.UIAxes2,x(tour),y(tour),'-hr',...
'LineWidth',3,...
'MarkerSize',15,...
'MarkerFaceColor',[0.1 0.9 0.2],...
'MarkerEdgeColor','b');

        end

        % Button pushed function: HSButton
        function HSButtonPushed(app, event)
            
global x;
x=x;
global y;
y=y;

model=MakeModel(x,y);

tic

CostFunction=@(s) CostF(s,model);        % Cost Function
nVar=model.n;             % Number of Decision Variables
VarSize=[1 nVar];         % Decision Variables Matrix Size
VarMin=0;                 % Lower Bound of Variables
VarMax=1;                 % Upper Bound of Variables


%% Harmony Search Parameters

MaxIt = app.IterationsEditField.Value;         % Maximum Number of Iterations
HMS = app.PopulationEditField.Value;           % Population Size
nNew = 10;        % Number of New Harmonies

HMCR = 0.9;       % Harmony Memory Consideration Rate
PAR = 0.1;        % Pitch Adjustment Rate
FW = 0.02*(VarMax-VarMin);    % Fret Width (Bandwidth)
FW_damp = 0.995;              % Fret Width Damp Ratio

%% Initialization

% Empty Harmony Structure
empty_harmony.Position = [];
empty_harmony.Cost = [];
empty_harmony.Sol = [];

% Initialize Harmony Memory
HM = repmat(empty_harmony, HMS, 1);

% Create Initial Harmonies
for i = 1:HMS
HM(i).Position = unifrnd(VarMin, VarMax, VarSize);
[HM(i).Cost HM(i).Sol] = CostFunction(HM(i).Position);
end

% Sort Harmony Memory
[~, SortOrder] = sort([HM.Cost]);
HM = HM(SortOrder);

% Update Best Solution Ever Found
BestSol = HM(1);

% Array to Hold Best Cost Values
BestCost = zeros(MaxIt, 1);

%% Harmony Search Main Loop

for it = 1:MaxIt

% Initialize Array for New Harmonies
NEW = repmat(empty_harmony, nNew, 1);

% Create New Harmonies
for k = 1:nNew

% Create New Harmony Position
NEW(k).Position = unifrnd(VarMin, VarMax, VarSize);
for j = 1:nVar
if rand <= HMCR
% Use Harmony Memory
i = randi([1 HMS]);
NEW(k).Position(j) = HM(i).Position(j);
end

% Pitch Adjustment
if rand <= PAR
%DELTA = FW*unifrnd(-1, +1);    % Uniform
DELTA = FW*randn();            % Gaussian (Normal) 
NEW(k).Position(j) = NEW(k).Position(j)+DELTA;
end

end

% Apply Variable Limits
NEW(k).Position = max(NEW(k).Position, VarMin);
NEW(k).Position = min(NEW(k).Position, VarMax);

% Evaluation
[NEW(k).Cost NEW(k).Sol] = CostFunction(NEW(k).Position);

end

% Merge Harmony Memory and New Harmonies
HM = [HM
NEW];

% Sort Harmony Memory
[~, SortOrder] = sort([HM.Cost]);
HM = HM(SortOrder);

% Truncate Extra Harmonies
HM = HM(1:HMS);

% Update Best Solution Ever Found
BestSol = HM(1);

% Store Best Cost Ever Found
BestCost(it) = BestSol.Cost;

% Show Iteration Information
disp(['Iteration ' num2str(it) ': Best Cost = ' num2str(BestCost(it))]);

% Damp Fret Width
FW = FW*FW_damp;

% Plot Res
% figure(1);
% Plotfig(BestSol.Sol.tour,model);
app.IterationNumberEditField.Value=double(it);
pause(0.000000001);
drawnow;
end

% Time and Cost
toc
time=toc;
app.TimeEditField.Value=double(time);
app.CostEditField.Value=double(BestCost(end));
TourChar=num2str(BestSol.Sol.tour);
app.TourTextArea.Value=TourChar;

%% Res
plot(app.UIAxes,BestCost,'k','LineWidth', 2);
tour=[BestSol.Sol.tour BestSol.Sol.tour(1)];
x=model.x;
y=model.y;
plot(app.UIAxes2,x(tour),y(tour),'-hr',...
'LineWidth',3,...
'MarkerSize',15,...
'MarkerFaceColor',[0.1 0.9 0.2],...
'MarkerEdgeColor','b');

        end

        % Button pushed function: FAButton
        function FAButtonPushed(app, event)
            
global x;
x=x;
global y;
y=y;

model=MakeModel(x,y);


tic


CostFunction=@(s) CostF(s,model);        % Cost Function
nVar=model.n;             % Number of Decision Variables
VarSize=[1 nVar];         % Decision Variables Matrix Size
VarMin=0;                 % Lower Bound of Variables
VarMax=1;                 % Upper Bound of Variables

%% Firefly Algorithm Parameters

MaxIt = app.IterationsEditField.Value;         % Maximum Number of Iterations
nPop = app.PopulationEditField.Value;           % Population Size


gamma = 2;            % Light Absorption Coefficient
beta0 = 4;            % Attraction Coefficient Base Value
alpha = 0.2;          % Mutation Coefficient
alpha_damp = 0.98;    % Mutation Coefficient Damping Ratio
delta = 0.05*(VarMax-VarMin);     % Uniform Mutation Range

m = 2;

if isscalar(VarMin) && isscalar(VarMax)
dmax = (VarMax-VarMin)*sqrt(nVar);
else
dmax = norm(VarMax-VarMin);
end

%% Initialization

% Empty Firefly Structure
firefly.Position = [];
firefly.Cost = [];
firefly.Sol = [];

% Initialize Population Array
pop = repmat(firefly, nPop, 1);

% Initialize Best Solution Ever Found
BestSol.Cost = inf;

% Create Initial Fireflies
for i = 1:nPop
pop(i).Position = unifrnd(VarMin, VarMax, VarSize);
[pop(i).Cost pop(i).Sol] = CostFunction(pop(i).Position);

if pop(i).Cost <= BestSol.Cost
BestSol = pop(i);
end
end

% Array to Hold Best Cost Values
BestCost = zeros(MaxIt, 1);

%% Firefly Algorithm Main Loop

for it = 1:MaxIt

newpop = repmat(firefly, nPop, 1);
for i = 1:nPop
newpop(i).Cost = inf;
for j = 1:nPop
if pop(j).Cost < pop(i).Cost
rij = norm(pop(i).Position-pop(j).Position)/dmax;
beta = beta0*exp(-gamma*rij^m);
e = delta*unifrnd(-1, +1, VarSize);
%e = delta*randn(VarSize);

newsol.Position = pop(i).Position ...
    + beta*rand(VarSize).*(pop(j).Position-pop(i).Position) ...
    + alpha*e;

newsol.Position = max(newsol.Position, VarMin);
newsol.Position = min(newsol.Position, VarMax);

[newsol.Cost newsol.Sol] = CostFunction(newsol.Position);

if newsol.Cost <= newpop(i).Cost
newpop(i) = newsol;
if newpop(i).Cost <= BestSol.Cost
BestSol = newpop(i);
end
end
end
end
end

% Merge
pop = [pop
newpop];  %#ok

% Sort
[~, SortOrder] = sort([pop.Cost]);
pop = pop(SortOrder);

% Truncate
pop = pop(1:nPop);

% Store Best Cost Ever Found
BestCost(it) = BestSol.Cost;

% Show Iteration Information
disp(['Iteration ' num2str(it) ': Best Cost = ' num2str(BestCost(it))]);

% Damp Mutation Coefficient
alpha = alpha*alpha_damp;

% Plot Res
% figure(1);
% Plotfig(BestSol.Sol.tour,model);
app.IterationNumberEditField.Value=double(it);
pause(0.000000001);
drawnow;
end

% Time and Cost
toc
time=toc;
app.TimeEditField.Value=double(time);
app.CostEditField.Value=double(BestCost(end));
TourChar=num2str(BestSol.Sol.tour);
app.TourTextArea.Value=TourChar;

%% Res
plot(app.UIAxes,BestCost,'k','LineWidth', 2);
tour=[BestSol.Sol.tour BestSol.Sol.tour(1)];
x=model.x;
y=model.y;
plot(app.UIAxes2,x(tour),y(tour),'-hr',...
'LineWidth',3,...
'MarkerSize',15,...
'MarkerFaceColor',[0.1 0.9 0.2],...
'MarkerEdgeColor','b');

        end

        % Callback function
        function BreakButtonPushed(app, event)
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TravellingSalesmanProblemTSPUIFigure = uifigure('Visible', 'off');
            app.TravellingSalesmanProblemTSPUIFigure.Name = 'Travelling Salesman Problem (TSP)';
            app.UIAxes = uiaxes(app.TravellingSalesmanProblemTSPUIFigure);
            xlabel(app.UIAxes, 'Iterations')
            ylabel(app.UIAxes, 'Cost Value')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.TravellingSalesmanProblemTSPUIFigure);
            title(app.UIAxes2, 'TSP')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.TLBOButton = uibutton(app.TravellingSalesmanProblemTSPUIFigure, 'push');
            app.TLBOButton.ButtonPushedFcn = createCallbackFcn(app, @TLBOButtonPushed, true);
            app.TLBOButton.Text = 'TLBO';
            app.IterationsEditFieldLabel = uilabel(app.TravellingSalesmanProblemTSPUIFigure);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.TravellingSalesmanProblemTSPUIFigure, 'numeric');
            app.IterationsEditField.Value = 100;
            app.PopulationEditFieldLabel = uilabel(app.TravellingSalesmanProblemTSPUIFigure);
            app.PopulationEditFieldLabel.Text = 'Population';
            app.PopulationEditField = uieditfield(app.TravellingSalesmanProblemTSPUIFigure, 'numeric');
            app.PopulationEditField.Value = 10;
            app.XTextAreaLabel = uilabel(app.TravellingSalesmanProblemTSPUIFigure);
            app.XTextAreaLabel.Text = 'X';
            app.XTextArea = uitextarea(app.TravellingSalesmanProblemTSPUIFigure);
            app.XTextArea.Editable = 'off';
            app.LoadCSVButton = uibutton(app.TravellingSalesmanProblemTSPUIFigure, 'push');
            app.LoadCSVButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCSVButtonPushed, true);
            app.LoadCSVButton.Text = 'Load CSV';
            app.YTextAreaLabel = uilabel(app.TravellingSalesmanProblemTSPUIFigure);
            app.YTextAreaLabel.Text = 'Y';
            app.YTextArea = uitextarea(app.TravellingSalesmanProblemTSPUIFigure);
            app.YTextArea.Editable = 'off';
            app.CostEditFieldLabel = uilabel(app.TravellingSalesmanProblemTSPUIFigure);
            app.CostEditFieldLabel.Text = 'Cost';
            app.CostEditField = uieditfield(app.TravellingSalesmanProblemTSPUIFigure, 'numeric');
            app.CostEditField.Editable = 'off';
            app.TimeEditFieldLabel = uilabel(app.TravellingSalesmanProblemTSPUIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.TravellingSalesmanProblemTSPUIFigure, 'numeric');
            app.TimeEditField.Editable = 'off';
            app.IterationNumberEditFieldLabel = uilabel(app.TravellingSalesmanProblemTSPUIFigure);
            app.IterationNumberEditFieldLabel.Text = 'Iteration Number';
            app.IterationNumberEditField = uieditfield(app.TravellingSalesmanProblemTSPUIFigure, 'numeric');
            app.IterationNumberEditField.Editable = 'off';
            app.DEButton = uibutton(app.TravellingSalesmanProblemTSPUIFigure, 'push');
            app.DEButton.ButtonPushedFcn = createCallbackFcn(app, @DEButtonPushed, true);
            app.DEButton.Text = 'DE';
            app.HSButton = uibutton(app.TravellingSalesmanProblemTSPUIFigure, 'push');
            app.HSButton.ButtonPushedFcn = createCallbackFcn(app, @HSButtonPushed, true);
            app.HSButton.Text = 'HS';
            app.FAButton = uibutton(app.TravellingSalesmanProblemTSPUIFigure, 'push');
            app.FAButton.ButtonPushedFcn = createCallbackFcn(app, @FAButtonPushed, true);
            app.FAButton.Text = 'FA';
            app.SecondsLabel = uilabel(app.TravellingSalesmanProblemTSPUIFigure);
            app.SecondsLabel.Text = 'Seconds';
            app.UnitsLabel = uilabel(app.TravellingSalesmanProblemTSPUIFigure);
            app.UnitsLabel.Text = 'Units';
            app.TourTextAreaLabel = uilabel(app.TravellingSalesmanProblemTSPUIFigure);
            app.TourTextAreaLabel.Text = 'Tour';
            app.TourTextArea = uitextarea(app.TravellingSalesmanProblemTSPUIFigure);
            app.TourTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.TravellingSalesmanProblemTSPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TSP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TravellingSalesmanProblemTSPUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TravellingSalesmanProblemTSPUIFigure)
        end
    end
end